classdef app1_GUI_lorenza_trajectoryplanning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        trapezoidal2cubic3quintic4linearLabel  matlab.ui.control.Label
        min_tempButton                matlab.ui.control.Button
        mintimeLabel                  matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        statemessagesLabel            matlab.ui.control.Label
        nextButton                    matlab.ui.control.Button
        previousButton                matlab.ui.control.Button
        computetrajectoryButton       matlab.ui.control.Button
        ChoseninterpolarionEditField  matlab.ui.control.NumericEditField
        ChoseninterpolarionEditFieldLabel  matlab.ui.control.Label
        TimerequiredEditField         matlab.ui.control.NumericEditField
        TimerequiredEditFieldLabel    matlab.ui.control.Label
        frequencyEditField            matlab.ui.control.NumericEditField
        frequencyEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
       % Robot
        robot
        q0
        eeName
        qTraj
        numSamples
        trajIndex
        ax3d

    end
    
    methods (Access = private)
        
        function renderStep(app) %drow the robot when it moves
            q_now = app.qTraj(:, app.trajIndex)';
            show(app.robot, q_now, 'Parent', app.ax3d, 'PreservePlot', false);
            view(app.ax3d, 45, 25);
            axis(app.ax3d, [-0.8 0.8 -0.8 0.8 -0.8 0.8]);
            grid(app.ax3d, 'on');
            drawnow
            
        end

        function phi_optimal = qarmInverseKinematics(app, p, gamma, phi_prev)
            L1 = 0.1400; L2 = 0.3500; L3 = 0.0500; L4 = 0.2500; L5 = 0.1500;
            beta    = atan(L3/L2);
            lambda2 = sqrt(L2^2 + L3^2);
            lambda3 = L4 + L5;
        
            theta = zeros(4,4);
            phi   = zeros(4,4);
        
            A = lambda2;
            C = -lambda3;
            H = L1 - p(3);
            D1 = -sqrt(p(1)^2 + p(2)^2);
            D2 =  sqrt(p(1)^2 + p(2)^2);
            F1 = (D1^2 + H^2 - A^2 - C^2)/(2*A);
            F2 = (D2^2 + H^2 - A^2 - C^2)/(2*A);
        
            % theta3
            if C^2 - F1^2 < 0
                theta(3,1) = sign(C)*pi/2; theta(3,2) = sign(C)*pi/2;
            else
                theta(3,1) = 2*atan2(C + sqrt(C^2-F1^2), F1);
                theta(3,2) = 2*atan2(C - sqrt(C^2-F1^2), F1);
            end
            if C^2 - F2^2 < 0
                theta(3,3) = sign(C)*pi/2; theta(3,4) = sign(C)*pi/2;
            else
                theta(3,3) = 2*atan2(C + sqrt(C^2-F2^2), F2);
                theta(3,4) = 2*atan2(C - sqrt(C^2-F2^2), F2);
            end
        
            % theta2
            for i = 1:4
                D = D1; if i > 2, D = D2; end
                M = A + C*sin(theta(3,i));
                N = -C*cos(theta(3,i));
                if abs(M) < 1e-6, M = sign(M)*1e-6; end
                cos_term   = (D*M + H*N)/(M^2 + N^2);
                sin_term   = (H - N*cos_term)/M;
                theta(2,i) = atan2(sin_term, cos_term);
            end
        
            % theta1
            for i = 1:4
                denom = lambda2*cos(theta(2,i)) - lambda3*sin(theta(2,i)+theta(3,i));
                if abs(denom) < 1e-6, denom = sign(denom)*1e-6; end
                theta(1,i) = atan2(p(2)/denom, p(1)/denom);
            end
        
            % Remap QArm
            phi(1,:) = theta(1,:);
            phi(2,:) = theta(2,:) - beta + pi/2;
            phi(3,:) = theta(3,:) + beta;
            phi(4,:) = gamma;
            phi = mod(phi+pi, 2*pi) - pi;
        
            % Satura joint limits
            limits = [-170 170; -80 80; -95 75; -160 160] * pi/180;
            for i = 1:4
                for j = 1:4
                    phi(j,i) = max(limits(j,1), min(limits(j,2), phi(j,i)));
                end
            end
        
            % Scegli soluzione più vicina a phi_prev
            phi_optimal = phi_prev;
            best_norm   = inf;
            for i = 1:4
                d = norm(phi(:,i) - phi_prev);
                if d < best_norm
                    best_norm   = d;
                    phi_optimal = phi(:,i);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot = loadrobot("quanserQArm", DataFormat="row");
            app.robot.Gravity = [0 0 -9.81];
            %app.q0 = homeConfiguration(app.robot); %DEFAULT INITIAL
            %POSITION
            
            % DESIDERED INITIAL POSITION
            q_home = homeConfiguration(app.robot);
            x0 = 0.3; y0 = 0.0; z0 = 0.3;
            app.q0 = app.qarmInverseKinematics([x0;y0;z0], 0, q_home')';
           
            %INITIAL RADIAN POSITION
            %app.q0 = [0, -pi/4, pi/4, 0];  % [joint1, joint2, joint3, joint4]

            app.eeName = app.robot.BodyNames{end};
        
            % Crea un axes normale dentro la UIFigure (non UIAxes)
            app.ax3d = axes(app.UIFigure);
            app.ax3d.Position = [0.32 0.35 0.65 0.60]; % [x y w h] normalizzati
        
            show(app.robot, app.q0, 'Parent', app.ax3d, 'PreservePlot', false);
            view(app.ax3d, 45, 25);
            axis(app.ax3d, [-0.8 0.8 -0.8 0.8 -0.8 0.8]);
            grid(app.ax3d, 'on');
        
            app.statemessagesLabel.Text = 'Ready.';
                            
        end

        % Button pushed function: computetrajectoryButton
        function compute_trajectory(app, event)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
        
            % Controllo workspace
            if (sqrt(x^2+y^2+z^2) > 0.7313*0.9) || ((x<=0) && (z<=0))
                app.statemessagesLabel.Text = 'Target out of workspace!';
                return;
            end
        
            app.statemessagesLabel.Text = 'Computing IK...'; drawnow;
        
            % IK
            %ik = inverseKinematics('RigidBodyTree', app.robot);
            %weights = [0.25 0.25 0.25 1 1 1];
            
            % --- PRIMO PER USARE IK
            
            %[q_end, solInfo] = ik(app.eeName, trvec2tform([x y z]), weights, app.q0);
            q_end = app.qarmInverseKinematics([x;y;z], 0, app.q0')';


            % Campioni e tempo
            freq = app.frequencyEditField.Value;
            T    = app.TimerequiredEditField.Value;
            if freq<=0, freq=50; end
            if T<=0,    T=3;     end
            app.numSamples = max(10, round(freq*T));
            T_used = max(T, max(abs(q_end-app.q0)./deg2rad([90 90 90 90]))*1.2);
        
            % Traiettoria
            waypoints = [app.q0', q_end'];
            t_vec     = linspace(0, T_used, app.numSamples);
            interp    = app.ChoseninterpolarionEditField.Value;
        
            switch round(interp)
                case 1, [app.qTraj,~,~] = trapveltraj(waypoints, app.numSamples, 'EndTime', T_used);
                case 2, [app.qTraj,~,~] = cubicpolytraj(waypoints, [0 T_used], t_vec);
                case 3, [app.qTraj,~,~] = quinticpolytraj(waypoints, [0 T_used], t_vec);
                case 4
                    app.qTraj = zeros(4, app.numSamples);
                    for j=1:4
                        app.qTraj(j,:) = linspace(app.q0(j), q_end(j), app.numSamples);
                    end
                otherwise
                    [app.qTraj,~,~] = trapveltraj(waypoints, app.numSamples, 'EndTime', T_used);
            end
        
            app.trajIndex = app.numSamples;
            %app.statemessagesLabel.Text = sprintf('Done! %d steps | IK: %s', app.numSamples, solInfo.Status);
            app.statemessagesLabel.Text = sprintf('Done! trajectory is ready, %d steps', app.numSamples);
            app.renderStep();


        end

        % Button pushed function: previousButton
        function previousBottonPushed(app, event)
            if app.trajIndex <= 1
                app.statemessagesLabel.Text = 'Already at first step!';
                return;
            end
            app.trajIndex = app.trajIndex - 1;
            app.renderStep();
            app.statemessagesLabel.Text = sprintf('Step %d / %d', app.trajIndex, app.numSamples);
            
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if app.trajIndex >= app.numSamples
                app.statemessagesLabel.Text = 'Already at last step!';
                return;
            end
            app.trajIndex = app.trajIndex + 1;
            app.renderStep();
            app.statemessagesLabel.Text = sprintf('Step %d / %d', app.trajIndex, app.numSamples);
            
        end

        % Button pushed function: min_tempButton
        function minTime(app, event)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
        
            % Controllo workspace
            % if (sqrt(x^2+y^2+z^2) > 0.7313*0.8) || ((x<=0) && (z<=0))
            %     app.mintimeLabel.Text = 'Out of workspace!';
            %     return;
            % end
        
            % IK
            q_end = app.qarmInverseKinematics([x;y;z], 0, app.q0')';
        
            % Calcola tempo minimo
            T_min = max(abs(q_end - app.q0) ./ deg2rad([90 90 90 90])) * 1.2;
        
            app.mintimeLabel.Text = sprintf('Min time: %.2f s', T_min); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.frequencyEditFieldLabel = uilabel(app.UIFigure);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimerequiredEditFieldLabel = uilabel(app.UIFigure);
            app.TimerequiredEditFieldLabel.Text = 'Time required';
            app.TimerequiredEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoseninterpolarionEditFieldLabel = uilabel(app.UIFigure);
            app.ChoseninterpolarionEditFieldLabel.Text = 'Chosen interpolarion: ';
            app.ChoseninterpolarionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoseninterpolarionEditField.Value = 1;
            app.computetrajectoryButton = uibutton(app.UIFigure, 'push');
            app.computetrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @compute_trajectory, true);
            app.computetrajectoryButton.Text = 'compute trajectory';
            app.previousButton = uibutton(app.UIFigure, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @previousBottonPushed, true);
            app.previousButton.Text = 'previous';
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.statemessagesLabel = uilabel(app.UIFigure);
            app.statemessagesLabel.Text = 'state messages:';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.mintimeLabel = uilabel(app.UIFigure);
            app.mintimeLabel.Text = 'min time:';
            app.min_tempButton = uibutton(app.UIFigure, 'push');
            app.min_tempButton.ButtonPushedFcn = createCallbackFcn(app, @minTime, true);
            app.min_tempButton.Text = 'min_temp';
            app.trapezoidal2cubic3quintic4linearLabel = uilabel(app.UIFigure);
            app.trapezoidal2cubic3quintic4linearLabel.Text = '1-trapezoidal, 2-cubic, 3- quintic, 4-linear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_GUI_lorenza_trajectoryplanning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end