classdef starting_potentialfield < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        stateLabel                   matlab.ui.control.Label
        time_stepsEditField          matlab.ui.control.NumericEditField
        time_stepsEditFieldLabel     matlab.ui.control.Label
        target_moving_timeEditField  matlab.ui.control.NumericEditField
        target_moving_timeEditFieldLabel  matlab.ui.control.Label
        targetfinalpositionLabel     matlab.ui.control.Label
        targetinitpositionLabel      matlab.ui.control.Label
        setButton_2                  matlab.ui.control.Button
        EditField_3                  matlab.ui.control.NumericEditField
        setButton                    matlab.ui.control.Button
        xEditFieldLabel_3            matlab.ui.control.Label
        xEditFieldLabel_2            matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        resetButton                  matlab.ui.control.Button
        stopButton                   matlab.ui.control.Button
        startButton                  matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
         %robot
         robot
         q0
         eeName
         qTraj
         numSamples
         trajIndex
         ax3d


         %target poit
         p_start = [0; 0; 0];
         p_end   = [0; 0; 0];

         %for the target point motion
         h_start = [0; 0; 0];
         BASE_OFFSET = [0; 0; 0];
         d_threshold = 0.002;

         %motion
         k_att = 2.0;      
         k_rep = 0.05;      
         v_max = 0.25; 
         p_current = [0; 0; 0]
         q_current;

         %cubic spline
         T_total
         spline_coeffs;
         dt;
         t_elapsed = 0
         app_timer;


         %constants
         MAX_ARM = 0.7
      
    end
    
    methods (Access = private)
        
        function drawGrid(app, ax, point, label, color, shape) %ax = UIAxes, UIAxes_2,
             cla (ax);

             hold(ax, 'on'); %HOLDS the plot when something else is done
        
             % Cerchio workspace
             theta = linspace(0, 2*pi, 200);
             h1 = plot(ax, app.MAX_ARM*cos(theta), app.MAX_ARM*sin(theta), 'b-', 'LineWidth', 1);
             h1.HitTest = 'off';
        
             % Pallino robot al centro
             h2 = plot(ax, 0, 0, 'ko', 'MarkerFaceColor', 'k', 'MarkerSize', 8);
             h2.HitTest = 'off';
        
             % Punto selezionato
             if ~isempty(point)
                
                h3 = plot(ax, point(1), point(2), shape, 'Color', color, 'MarkerSize', 6, 'MarkerFaceColor', color, 'LineWidth', 1.5);
                h3.HitTest = 'off';
                                 

             end
        
             title(ax, label);
             hold(ax, 'off');

                    
        end

        function p = evalSpline(app, t)
            t = min(t, app.T_total);
            p = zeros(3,1);
            for i = 1:3
                a = app.spline_coeffs(i,:);
                p(i) = a(1) + a(2)*t + a(3)*t^2 + a(4)*t^3;
            end
        end


        function p_new = potentialFieldStep(app, p_robot, p_target)
            diff_vec = p_target - p_robot;
            dist = norm(diff_vec);
            if dist < app.d_threshold
                p_new = p_robot;
                return;
            end
            F_att = app.k_att * diff_vec;
            p_new = p_robot + app.dt * F_att;
        end


        function phi_optimal = qarmInverseKinematics(app, p, gamma, phi_prev)
            L1 = 0.1400; L2 = 0.3500; L3 = 0.0500; L4 = 0.2500; L5 = 0.1500;
            beta    = atan(L3/L2);
            lambda2 = sqrt(L2^2 + L3^2);
            lambda3 = L4 + L5;
        
            theta = zeros(4,4);
            phi   = zeros(4,4);
        
            A = lambda2;
            C = -lambda3;
            H = L1 - p(3);
            D1 = -sqrt(p(1)^2 + p(2)^2);
            D2 =  sqrt(p(1)^2 + p(2)^2);
            F1 = (D1^2 + H^2 - A^2 - C^2)/(2*A);
            F2 = (D2^2 + H^2 - A^2 - C^2)/(2*A);
        
            % theta3
            if C^2 - F1^2 < 0
                theta(3,1) = sign(C)*pi/2; theta(3,2) = sign(C)*pi/2;
            else
                theta(3,1) = 2*atan2(C + sqrt(C^2-F1^2), F1);
                theta(3,2) = 2*atan2(C - sqrt(C^2-F1^2), F1);
            end
            if C^2 - F2^2 < 0
                theta(3,3) = sign(C)*pi/2; theta(3,4) = sign(C)*pi/2;
            else
                theta(3,3) = 2*atan2(C + sqrt(C^2-F2^2), F2);
                theta(3,4) = 2*atan2(C - sqrt(C^2-F2^2), F2);
            end
        
            % theta2
            for i = 1:4
                D = D1; if i > 2, D = D2; end
                M = A + C*sin(theta(3,i));
                N = -C*cos(theta(3,i));
                if abs(M) < 1e-6, M = sign(M)*1e-6; end
                cos_term   = (D*M + H*N)/(M^2 + N^2);
                sin_term   = (H - N*cos_term)/M;
                theta(2,i) = atan2(sin_term, cos_term);
            end
        
            % theta1
            for i = 1:4
                denom = lambda2*cos(theta(2,i)) - lambda3*sin(theta(2,i)+theta(3,i));
                if abs(denom) < 1e-6, denom = sign(denom)*1e-6; end
                theta(1,i) = atan2(p(2)/denom, p(1)/denom);
            end
        
            % Remap QArm
            phi(1,:) = theta(1,:);
            phi(2,:) = theta(2,:) - beta + pi/2;
            phi(3,:) = theta(3,:) + beta;
            phi(4,:) = gamma;
            phi = mod(phi+pi, 2*pi) - pi;
        
            % Satura joint limits
            limits = [-170 170; -80 80; -95 75; -160 160] * pi/180;
            for i = 1:4
                for j = 1:4
                    phi(j,i) = max(limits(j,1), min(limits(j,2), phi(j,i)));
                end
            end
        
            % Scegli soluzione più vicina a phi_prev
            phi_optimal = phi_prev;
            best_norm   = inf;
            for i = 1:4
                d = norm(phi(:,i) - phi_prev);
                if d < best_norm
                    best_norm   = d;
                    phi_optimal = phi(:,i);
                end
            end


            end
        
        function timerCallback(app)
            % 1. time
                app.t_elapsed = app.t_elapsed + app.dt;
                
                % 2. position of the target on the spline
                p_target = app.evalSpline(app.t_elapsed);
                
                % 3. Calcola nuovo passo del potential field
                app.p_current = app.potentialFieldStep(app.p_current, p_target);
                
                % 4. robot joint position computatiom
                app.q_current = app.qarmInverseKinematics(app.p_current, 0, app.q_current')';
                
                % 5. redraw of the robot in the new position
                    %save the position of the camera for the robot
                current_view = [app.ax3d.View(1), app.ax3d.View(2)];
                current_axis = axis(app.ax3d);


                show(app.robot, app.q_current, 'Parent', app.ax3d, 'PreservePlot', false);

                    %put the previous orentation
                view(app.ax3d, current_view(1), current_view(2));
                axis(app.ax3d, current_axis);
                grid(app.ax3d, 'on');
                
                %6. redraw the init target with the new position of the
                %target
                delete(findobj(app.ax3d, 'Tag', 'init_marker'));
                hold(app.ax3d, 'on');
                plot3(app.ax3d, p_target(1), p_target(2), p_target(3), 'o', 'Color', 'r', 'MarkerSize', 8, 'LineWidth', 1.5, 'Tag', 'init_marker');
                plot3(app.ax3d, app.p_end(1), app.p_end(2), app.p_end(3), '+', 'Color', 'b', 'MarkerSize', 10, 'LineWidth', 1.5, 'Tag', 'end_marker');
                hold(app.ax3d, 'off');
                drawnow limitrate;
               
                
                % 6. did the robot reach the target? 
                dist = norm(app.p_current - p_target);
                if dist < app.d_threshold
                    app.stopButtonPushed([]);  % ferma il timer
                    % Mostra risultato
                    app.targetinitpositionLabel.Text = sprintf(...
                        'RAGGIUNTO! t=%.2fs  x=%.3f y=%.3f z=%.3f', ...
                        app.t_elapsed, app.p_current(1), app.p_current(2), app.p_current(3));
            
               end
        end
        
        function computeSpline(app)
            T = app.T_total;
            for i = 1:3
                p0 = app.p_start(i);
                pf = app.p_end(i);
                app.spline_coeffs(i,:) = [p0, 0, 3*(pf-p0)/T^2, -2*(pf-p0)/T^3];
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes_2);


            %robot display
            app.robot = loadrobot("quanserQArm", DataFormat="row");
            app.robot.Gravity = [0 0 -9.81];
            %app.q0 = homeConfiguration(app.robot); %DEFAULT INITIAL
            %POSITION
            
            % DESIDERED INITIAL POSITION
            q_home = homeConfiguration(app.robot);
            x0 = 0.3; y0 = 0.0; z0 = 0.3;
            app.q0 = app.qarmInverseKinematics([x0;y0;z0], 0, q_home')';
           
            %INITIAL RADIANT POSITION
            %app.q0 = [0, -pi/4, pi/4, 0];  % [joint1, joint2, joint3, joint4]

            app.eeName = app.robot.BodyNames{end};
        
            % Crea un axes normale dentro la UIFigure (non UIAxes)
            app.ax3d = axes(app.UIFigure);
            app.ax3d.Position = [0.32 0.35 0.65 0.60]; % [x y w h] normalizzati
        
            show(app.robot, app.q0, 'Parent', app.ax3d, 'PreservePlot', false);
            view(app.ax3d, 130, 25);
            axis(app.ax3d, [-0.8 0.8 -0.8 0.8 -0.1 0.9]);
            grid(app.ax3d, 'on');

            h_rotate = rotate3d(app.ax3d);
            %h_rotate.Enable = 'on';
            
            
            % disable the rotation on the 2d plots
            setAllowAxesRotate(h_rotate, app.UIAxes, false);
            setAllowAxesRotate(h_rotate, app.UIAxes_2, false);
            
            % enables the rotation on the 3d plot
            setAllowAxesRotate(h_rotate, app.ax3d, true);


        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes
        function init_target_position(app, event)
            cp = app.UIAxes.CurrentPoint; %read the point
            x  = cp(1,1); %extract from the 2x3 ridden matrix
            y  = cp(1,2);
        
            % if out of range, move inside
            r = sqrt(x^2 + y^2);
            if r > app.MAX_ARM
                x = x * app.MAX_ARM/r;
                y = y * app.MAX_ARM/r;
            end
        
            % saving
            app.p_start(1) = x;
            app.p_start(2) = y;
            
            
            % drowing on the grip
            app.drawGrid(app.UIAxes, [x; y], 'Start (x,y)', 'r', 'o');
            %app.drawGrid(app.UIAxes_2, [x; y], 'Start (x,y)');

            app.targetinitpositionLabel.Text = sprintf('x=%.3f  y=%.3f z=%.3f', x, y, app.p_start(3));


        end

        % Button pushed function: setButton
        function init_set(app, event)
            
            app.p_start(3) = app.EditField_2.Value;
            app.targetinitpositionLabel.Text = sprintf('x=%.3f  y=%.3f  z=%.3f', app.p_start(1), app.p_start(2), app.p_start(3));
            
            %drawing on the robotic plot
            delete(findobj(app.ax3d, 'Tag', 'init_marker')); %removing a precedent object with the same tag if present

            hold(app.ax3d, 'on');
            plot3(app.ax3d, app.p_start(1)-app.BASE_OFFSET(1), app.p_start(2)-app.BASE_OFFSET(1), app.p_start(2)-app.BASE_OFFSET(3),'o', 'Color', 'r', 'MarkerSize', 6, 'LineWidth', 1.5, 'Tag', 'init_marker');  % Tag to be able to delete and replace it
            hold(app.ax3d, 'off');
            

        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            cp = app.UIAxes_2.CurrentPoint; %read the point
            x  = cp(1,1); %extract from the 2x3 ridden matrix
            y  = cp(1,2);
        
            % if out of range, move inside
            r = sqrt(x^2 + y^2);
            if r > app.MAX_ARM
                x = x * app.MAX_ARM/r;
                y = y * app.MAX_ARM/r;
            end 
            app.p_end(1) = x;
            app.p_end(2) = y;

            app.drawGrid(app.UIAxes_2, [x; y], 'End (x,y)', 'b', '+');
            app.targetfinalpositionLabel.Text = sprintf('x=%.3f y=%.3f z=%.3f', x, y, app.p_end(3));
        end

        % Button pushed function: setButton_2
        function setButton_2Pushed(app, event)
            app.p_end(3) = app.EditField_3.Value;
            app.targetinitpositionLabel.Text = sprintf('x=%.3f  y=%.3f  z=%.3f', app.p_end(1), app.p_end(2), app.p_end(3));

            %drawing a blu x on the 3d robot plot
            delete(findobj(app.ax3d, 'Tag', 'end_marker')); %removing a precedent object with the same tag if present
            
            hold(app.ax3d, 'on');
            plot3(app.ax3d, app.p_end(1), app.p_end(2), app.p_end(3),'+', 'Color', 'b', 'MarkerSize', 6, 'LineWidth', 1.5, 'Tag', 'end_marker');  % Tag per poterlo cancellare se serve
            hold(app.ax3d, 'off');

        end

        % Button pushed function: startButton
        function start_simulation(app, event)
            app.T_total = app.target_moving_timeEditField.Value;  % tempo totale movimento
            app.dt      = app.time_stepsEditField.Value;      % intervallo timestep
            
            % 2. Reset tempo
            app.t_elapsed = 0;
            
            % 3. Configurazione iniziale del robot = posizione corrente
            app.q_current = app.q0;
            app.p_current = app.p_start;
            
            % 4. Calcola i coefficienti della spline
            app.computeSpline();
            
            % 5. Avvia il timer
            app.app_timer = timer('ExecutionMode', 'fixedRate', 'Period',         app.dt, ...
                'TimerFcn',       @(~,~) app.timerCallback());
            start(app.app_timer);
            
            
        end

        % Button pushed function: stopButton
        function stop_simulation(app, event)
            
                if ~isempty(app.app_timer) && isvalid(app.app_timer)
                    stop(app.app_timer);
                    delete(app.app_timer);
                    app.app_timer = [];
                end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            % 1. Ferma il timer se sta girando
            if ~isempty(app.app_timer) && isvalid(app.app_timer)
                stop(app.app_timer);
                delete(app.app_timer);
                app.app_timer = [];
            end
            
            % 2. Reset tempo
            app.t_elapsed = 0;
            
            % 3. Reset posizione robot alla posizione iniziale
            app.q_current = app.q0;
            app.p_current = app.p_start;
            
            % 4. Ridisegna robot nella posizione iniziale
            current_view = [app.ax3d.View(1), app.ax3d.View(2)];
            current_axis = axis(app.ax3d);
            show(app.robot, app.q0, 'Parent', app.ax3d, 'PreservePlot', false);
            view(app.ax3d, current_view(1), current_view(2));
            axis(app.ax3d, current_axis);
            grid(app.ax3d, 'on');
            
            % 5. Ridisegna i marker (show ha cancellato tutto)
            hold(app.ax3d, 'on');
            plot3(app.ax3d, app.p_start(1), app.p_start(2), app.p_start(3), ...
                  'o', 'Color', 'r', 'MarkerSize', 8, 'LineWidth', 2, 'Tag', 'init_marker');
            plot3(app.ax3d, app.p_end(1), app.p_end(2), app.p_end(3), ...
                  '+', 'Color', 'b', 'MarkerSize', 10, 'LineWidth', 2.5, 'Tag', 'end_marker');
            hold(app.ax3d, 'off');
            
            % 6. Reset label stato
            app.stateLabel.Text = 'Reset effettuato';
            app.ready_to_start = false;
            app.startButton.Enable = 'off';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'initial target position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-0.7 0.7];
            app.UIAxes.YLim = [-0.7 0.7];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-0.7 0 0.7];
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @init_target_position, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'final target position')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-0.7 0.7];
            app.UIAxes_2.YLim = [-0.7 0.7];
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.XTick = [-0.7 0 0.7];
            app.UIAxes_2.YAxisLocation = 'origin';
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @start_simulation, true);
            app.startButton.Text = 'start ';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stop_simulation, true);
            app.stopButton.Text = 'stop';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Tag = 'z_init';
            app.xEditFieldLabel_2 = uilabel(app.UIFigure);
            app.xEditFieldLabel_2.Text = 'z_in target';
            app.xEditFieldLabel_3 = uilabel(app.UIFigure);
            app.xEditFieldLabel_3.Text = 'z_fi target';
            app.setButton = uibutton(app.UIFigure, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @init_set, true);
            app.setButton.Text = 'set';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Tag = 'z_end';
            app.setButton_2 = uibutton(app.UIFigure, 'push');
            app.setButton_2.ButtonPushedFcn = createCallbackFcn(app, @setButton_2Pushed, true);
            app.setButton_2.Text = 'set';
            app.targetinitpositionLabel = uilabel(app.UIFigure);
            app.targetinitpositionLabel.Text = 'target init position';
            app.targetfinalpositionLabel = uilabel(app.UIFigure);
            app.targetfinalpositionLabel.Text = 'target final position';
            app.target_moving_timeEditFieldLabel = uilabel(app.UIFigure);
            app.target_moving_timeEditFieldLabel.Text = 'target_moving _time';
            app.target_moving_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.time_stepsEditFieldLabel = uilabel(app.UIFigure);
            app.time_stepsEditFieldLabel.Text = 'time_steps';
            app.time_stepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.stateLabel = uilabel(app.UIFigure);
            app.stateLabel.Text = 'state';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = starting_potentialfield

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end